classdef AugmentedPhasePlane < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AddOrbitsPanel             matlab.ui.container.Panel
        IterationsEditField        matlab.ui.control.NumericEditField
        IterationsEditFieldLabel   matlab.ui.control.Label
        InitialYEditField          matlab.ui.control.NumericEditField
        InitialYEditFieldLabel     matlab.ui.control.Label
        InitialXEditField          matlab.ui.control.NumericEditField
        InitialXEditFieldLabel     matlab.ui.control.Label
        AddOrbitButton             matlab.ui.control.Button
        OptionalInputsPanel        matlab.ui.container.Panel
        OptimizeNullclineCombinationCheckBox  matlab.ui.control.CheckBox
        CombineNullclineSegmentsCheckBox  matlab.ui.control.CheckBox
        YcutoffEditField           matlab.ui.control.NumericEditField
        YcutoffEditFieldLabel      matlab.ui.control.Label
        XcutoffEditField           matlab.ui.control.NumericEditField
        XcutoffEditFieldLabel      matlab.ui.control.Label
        CutoffCurvesCheckBox       matlab.ui.control.CheckBox
        maxyEditField              matlab.ui.control.NumericEditField
        maxyEditFieldLabel         matlab.ui.control.Label
        maxxEditField              matlab.ui.control.NumericEditField
        maxxEditFieldLabel         matlab.ui.control.Label
        minyEditField              matlab.ui.control.NumericEditField
        minyEditFieldLabel         matlab.ui.control.Label
        minxEditField_5            matlab.ui.control.NumericEditField
        minxEditField_5Label       matlab.ui.control.Label
        NullclineCalculationRangeCheckBox  matlab.ui.control.CheckBox
        AccuracySlider             matlab.ui.control.Slider
        AccuracySliderLabel        matlab.ui.control.Label
        InputsPanel                matlab.ui.container.Panel
        XequationEditField         matlab.ui.control.EditField
        XequationEditFieldLabel    matlab.ui.control.Label
        YequationEditField         matlab.ui.control.EditField
        YequationEditFieldLabel    matlab.ui.control.Label
        minxEditField              matlab.ui.control.NumericEditField
        minxLabel                  matlab.ui.control.Label
        minxEditFieldLabel_3       matlab.ui.control.Label
        minxEditField_3            matlab.ui.control.NumericEditField
        minxEditFieldLabel_2       matlab.ui.control.Label
        minxEditField_2            matlab.ui.control.NumericEditField
        minxEditFieldLabel_4       matlab.ui.control.Label
        minxEditField_4            matlab.ui.control.NumericEditField
        InstructionsTextAreaLabel  matlab.ui.control.Label
        InstructionsTextArea       matlab.ui.control.TextArea
        CreateorUpdatePhasePortraitButton  matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        f_input = @(x,y) 3*x./(1+2*x+y); % X-function
        g_input = @(x,y) 3*y./(1+2*y+x); % Y-function
        minplotx = 0; % plotting range
        minploty = 0; 
        maxplotx = 2;
        maxploty = 2; 
        acc2 = 23; % accuracy
        nullclineRange = [0 3 -1 3]% nullcline calculation range
        nullclineRangeProvided = 1 % 1 if nullcline calculation range provided
        cutoffValuesProvided = 0 % 1 if cuttoff values provided
        cutoffVals = [0 0] % default cutoff values
        InitialvalueX = 0 % default initial X for orbit 
        InitialvalueY = 0 % default initial Y for orbit 
        NrIterations = 10 % default number of iterations for orbit 
        CombiningStatus = "on" % automatically combine nullcline segments 
        OptimizingStatus = "off" % don't automatically use segment combination optimizer
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XequationEditField
        function XequationEditFieldValueChanged(app, event)
            value = app.XequationEditField.Value; %inputted X-equation
            value2 = str2func(value); %change to function handle form
            app.f_input = value2; %store in property variable
        end

        % Value changed function: YequationEditField
        function YequationEditFieldValueChanged(app, event)
            value = app.YequationEditField.Value; %inputted Y-equation
            value2 = str2func(value); % change to function handle form
            app.g_input = value2; %store value
        end

        % Value changed function: minxEditField
        function minxEditFieldValueChanged(app, event)
            value = app.minxEditField.Value; 
            app.minplotx = value; %update minimum x for axes limits
        end

        % Value changed function: minxEditField_2
        function minxEditField_2ValueChanged(app, event)
            value = app.minxEditField_2.Value;
            app.maxplotx = value; %update maximum x for axes limits
        end

        % Value changed function: minxEditField_3
        function minxEditField_3ValueChanged(app, event)
            value = app.minxEditField_3.Value;
            app.minploty = value; %update minimum y for axes limits
        end

        % Value changed function: minxEditField_4
        function minxEditField_4ValueChanged(app, event)
            value = app.minxEditField_4.Value;
            app.maxploty = value; %update maximum y for axes limits
        end

        % Button pushed function: CreateorUpdatePhasePortraitButton
        function CreateorUpdatePhasePortraitButtonPushed(app, event)
            cla(app.UIAxes,"reset") %clear the axes so we do not plot overtop of the previous figure
            
            % make the phase portrait
            if app.nullclineRangeProvided == 1
                if app.cutoffValuesProvided == 1
                    % input with nullcline range and cutoff values options
                    augmented_implicit(app.f_input,app.g_input,app.minplotx,app.maxplotx,app.minploty,app.maxploty,"PortraitAxes",app.UIAxes,"Accuracy",app.acc2,"NullclineRange",app.nullclineRange,"CutoffValues",app.cutoffVals,"CombineNullcline",app.CombiningStatus,"OptimizeNullclineCombination",app.OptimizingStatus)
                else
                    % input with nullcline range option
                    augmented_implicit(app.f_input,app.g_input,app.minplotx,app.maxplotx,app.minploty,app.maxploty,"PortraitAxes",app.UIAxes,"Accuracy",app.acc2,"NullclineRange",app.nullclineRange,"CombineNullcline",app.CombiningStatus,"OptimizeNullclineCombination",app.OptimizingStatus)
                end
            else
                if app.cutoffValuesProvided == 1
                    % input with cutoff values option
                    augmented_implicit(app.f_input,app.g_input,app.minplotx,app.maxplotx,app.minploty,app.maxploty,"PortraitAxes",app.UIAxes,"Accuracy",app.acc2,"CutoffValues",app.cutoffVals,"CombineNullcline",app.CombiningStatus,"OptimizeNullclineCombination",app.OptimizingStatus)
                else
                    augmented_implicit(app.f_input,app.g_input,app.minplotx,app.maxplotx,app.minploty,app.maxploty,"PortraitAxes",app.UIAxes,"Accuracy",app.acc2,"CombineNullcline",app.CombiningStatus,"OptimizeNullclineCombination",app.OptimizingStatus)
                end
            end
        end

        % Value changed function: AccuracySlider
        function AccuracySliderValueChanged(app, event)
            value = app.AccuracySlider.Value;
            app.acc2 = value; %store new accuracy value
        end

        % Value changed function: NullclineCalculationRangeCheckBox
        function NullclineCalculationRangeCheckBoxValueChanged(app, event)
            value = app.NullclineCalculationRangeCheckBox.Value;
            if value == 0 %if checkbox is not marked
               app.nullclineRangeProvided = 0; % no nullcline calculation range provided
            elseif value == 1 % if checkbpx is maked
                app.nullclineRangeProvided = 1; % nullcline calculation range provided
            end
        end

        % Value changed function: minxEditField_5
        function minxEditField_5ValueChanged(app, event)
            value = app.minxEditField_5.Value;
            app.nullclineRange(1) = value; % store minimum x for nullcline calculation range
        end

        % Value changed function: maxxEditField
        function maxxEditFieldValueChanged(app, event)
            value = app.maxxEditField.Value;
            app.nullclineRange(2) = value; % store maximum x for nullcline calculation range
        end

        % Value changed function: minyEditField
        function minyEditFieldValueChanged(app, event)
            value = app.minyEditField.Value;
            app.nullclineRange(3) = value; % store minimum y for nullcline calculation range
        end

        % Value changed function: maxyEditField
        function maxyEditFieldValueChanged(app, event)
            value = app.maxyEditField.Value;
            app.nullclineRange(4) = value; % store maximum y for nullcline calculation range
        end

        % Value changed function: CutoffCurvesCheckBox
        function CutoffCurvesCheckBoxValueChanged(app, event)
            value = app.CutoffCurvesCheckBox.Value;
            if value == 0 % if checkbox not marked
               app.cutoffValuesProvided = 0; %cutoff values not wanted
            elseif value == 1 %if checkbox marked
                app.cutoffValuesProvided = 1; %cutoff values wanted
            end
        end

        % Value changed function: XcutoffEditField
        function XcutoffEditFieldValueChanged(app, event)
            value = app.XcutoffEditField.Value;
            app.cutoffVals(1) = value; %store cutoff value for x
        end

        % Value changed function: YcutoffEditField
        function YcutoffEditFieldValueChanged(app, event)
            value = app.YcutoffEditField.Value;
            app.cutoffVals(2) = value;%store cutoff value for y
        end

        % Value changed function: InitialXEditField
        function InitialXEditFieldValueChanged(app, event)
            value = app.InitialXEditField.Value;
            app.InitialvalueX = value; % store initial x value for orbit
        end

        % Value changed function: InitialYEditField
        function InitialYEditFieldValueChanged(app, event)
            value = app.InitialYEditField.Value;
            app.InitialvalueY = value; % store initial y value for orbit
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.NrIterations = value; % store number of iterations for orbit
        end

        % Button pushed function: AddOrbitButton
        function AddOrbitButtonPushed(app, event)
            %add an orbit when the button is pushed
            add_orbit(app.f_input,app.g_input,app.InitialvalueX,app.InitialvalueY,app.NrIterations,'OrbitAxes',app.UIAxes)
        end

        % Value changed function: CombineNullclineSegmentsCheckBox
        function CombineNullclineSegmentsCheckBoxValueChanged(app, event)
            value = app.CombineNullclineSegmentsCheckBox.Value;
            if value == 1 % if checkbox marked
               app.CombiningStatus = "on"; %automatically combine nullclines
            elseif value == 0 % if checkbox empty
                app.CombiningStatus = "off"; % turn off nullcline combining
            end
        end

        % Value changed function: OptimizeNullclineCombinationCheckBox
        function OptimizeNullclineCombinationCheckBoxValueChanged(app, event)
            value = app.OptimizeNullclineCombinationCheckBox.Value;
            if value ==1 
                app.OptimizingStatus = "on";
            else
                app.OptimizingStatus = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Phase Portrait')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CreateorUpdatePhasePortraitButton = uibutton(app.UIFigure, 'push');
            app.CreateorUpdatePhasePortraitButton.ButtonPushedFcn = createCallbackFcn(app, @CreateorUpdatePhasePortraitButtonPushed, true);
            app.CreateorUpdatePhasePortraitButton.Text = 'Create or Update Phase Portrait';
            app.InstructionsTextArea = uitextarea(app.UIFigure);
            app.InstructionsTextArea.Value = {'Properly vectorize functions (using .* and ./ and .^)'; ''; 'To have the root curves and symbols extend further increase the nullcline calculation range'; ''; 'Stars indicate that the next iterate operator is undefined because the point gets mapped outside the range of the nullcline.'; ''; 'The "Combine Nullcline Segments" option will automatically join nullcline segments which can form one continuous function. '; 'The "Optimize Nullcline Combination" option will join nullclines to mimimize the final number of nullclines for both X and Y.'};
            app.InstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.minxEditField_4 = uieditfield(app.InputsPanel, 'numeric');
            app.minxEditField_4.ValueChangedFcn = createCallbackFcn(app, @minxEditField_4ValueChanged, true);
            app.minxEditField_4.Value = 2;
            app.minxEditFieldLabel_4 = uilabel(app.InputsPanel);
            app.minxEditFieldLabel_4.Text = 'max y';
            app.minxEditField_2 = uieditfield(app.InputsPanel, 'numeric');
            app.minxEditField_2.ValueChangedFcn = createCallbackFcn(app, @minxEditField_2ValueChanged, true);
            app.minxEditField_2.Value = 2;
            app.minxEditFieldLabel_2 = uilabel(app.InputsPanel);
            app.minxEditFieldLabel_2.Text = 'max x';
            app.minxEditField_3 = uieditfield(app.InputsPanel, 'numeric');
            app.minxEditField_3.ValueChangedFcn = createCallbackFcn(app, @minxEditField_3ValueChanged, true);
            app.minxEditFieldLabel_3 = uilabel(app.InputsPanel);
            app.minxEditFieldLabel_3.Text = 'min y';
            app.minxLabel = uilabel(app.InputsPanel);
            app.minxLabel.Text = 'min x';
            app.minxEditField = uieditfield(app.InputsPanel, 'numeric');
            app.minxEditField.ValueChangedFcn = createCallbackFcn(app, @minxEditFieldValueChanged, true);
            app.YequationEditFieldLabel = uilabel(app.InputsPanel);
            app.YequationEditFieldLabel.Text = 'Y equation';
            app.YequationEditField = uieditfield(app.InputsPanel, 'text');
            app.YequationEditField.ValueChangedFcn = createCallbackFcn(app, @YequationEditFieldValueChanged, true);
            app.YequationEditField.Value = '@(x,y) 3*y./(1+2*y+x)';
            app.XequationEditFieldLabel = uilabel(app.InputsPanel);
            app.XequationEditFieldLabel.Text = 'X equation';
            app.XequationEditField = uieditfield(app.InputsPanel, 'text');
            app.XequationEditField.ValueChangedFcn = createCallbackFcn(app, @XequationEditFieldValueChanged, true);
            app.XequationEditField.Value = '@(x,y) 3*x./(1+2*x+y)';
            app.OptionalInputsPanel = uipanel(app.UIFigure);
            app.OptionalInputsPanel.Title = 'Optional Inputs';
            app.AccuracySliderLabel = uilabel(app.OptionalInputsPanel);
            app.AccuracySliderLabel.Text = 'Accuracy';
            app.AccuracySlider = uislider(app.OptionalInputsPanel);
            app.AccuracySlider.Limits = [10 50];
            app.AccuracySlider.ValueChangedFcn = createCallbackFcn(app, @AccuracySliderValueChanged, true);
            app.AccuracySlider.Value = 23;
            app.NullclineCalculationRangeCheckBox = uicheckbox(app.OptionalInputsPanel);
            app.NullclineCalculationRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NullclineCalculationRangeCheckBoxValueChanged, true);
            app.NullclineCalculationRangeCheckBox.Text = 'Nullcline Calculation Range';
            app.NullclineCalculationRangeCheckBox.Value = true;
            app.minxEditField_5Label = uilabel(app.OptionalInputsPanel);
            app.minxEditField_5Label.Text = 'min x';
            app.minxEditField_5 = uieditfield(app.OptionalInputsPanel, 'numeric');
            app.minxEditField_5.ValueChangedFcn = createCallbackFcn(app, @minxEditField_5ValueChanged, true);
            app.minyEditFieldLabel = uilabel(app.OptionalInputsPanel);
            app.minyEditFieldLabel.Text = 'min y';
            app.minyEditField = uieditfield(app.OptionalInputsPanel, 'numeric');
            app.minyEditField.ValueChangedFcn = createCallbackFcn(app, @minyEditFieldValueChanged, true);
            app.minyEditField.Value = -1;
            app.maxxEditFieldLabel = uilabel(app.OptionalInputsPanel);
            app.maxxEditFieldLabel.Text = 'max x';
            app.maxxEditField = uieditfield(app.OptionalInputsPanel, 'numeric');
            app.maxxEditField.ValueChangedFcn = createCallbackFcn(app, @maxxEditFieldValueChanged, true);
            app.maxxEditField.Value = 3;
            app.maxyEditFieldLabel = uilabel(app.OptionalInputsPanel);
            app.maxyEditFieldLabel.Text = 'max y';
            app.maxyEditField = uieditfield(app.OptionalInputsPanel, 'numeric');
            app.maxyEditField.ValueChangedFcn = createCallbackFcn(app, @maxyEditFieldValueChanged, true);
            app.maxyEditField.Value = 3;
            app.CutoffCurvesCheckBox = uicheckbox(app.OptionalInputsPanel);
            app.CutoffCurvesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CutoffCurvesCheckBoxValueChanged, true);
            app.CutoffCurvesCheckBox.Text = 'Cutoff Curves';
            app.XcutoffEditFieldLabel = uilabel(app.OptionalInputsPanel);
            app.XcutoffEditFieldLabel.Text = 'X cutoff';
            app.XcutoffEditField = uieditfield(app.OptionalInputsPanel, 'numeric');
            app.XcutoffEditField.ValueChangedFcn = createCallbackFcn(app, @XcutoffEditFieldValueChanged, true);
            app.YcutoffEditFieldLabel = uilabel(app.OptionalInputsPanel);
            app.YcutoffEditFieldLabel.Text = 'Y cutoff';
            app.YcutoffEditField = uieditfield(app.OptionalInputsPanel, 'numeric');
            app.YcutoffEditField.ValueChangedFcn = createCallbackFcn(app, @YcutoffEditFieldValueChanged, true);
            app.CombineNullclineSegmentsCheckBox = uicheckbox(app.OptionalInputsPanel);
            app.CombineNullclineSegmentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CombineNullclineSegmentsCheckBoxValueChanged, true);
            app.CombineNullclineSegmentsCheckBox.Text = 'Combine Nullcline Segments';
            app.CombineNullclineSegmentsCheckBox.Value = true;
            app.OptimizeNullclineCombinationCheckBox = uicheckbox(app.OptionalInputsPanel);
            app.OptimizeNullclineCombinationCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimizeNullclineCombinationCheckBoxValueChanged, true);
            app.OptimizeNullclineCombinationCheckBox.Text = 'Optimize Nullcline Combination';
            app.AddOrbitsPanel = uipanel(app.UIFigure);
            app.AddOrbitsPanel.Title = 'Add Orbits';
            app.AddOrbitButton = uibutton(app.AddOrbitsPanel, 'push');
            app.AddOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @AddOrbitButtonPushed, true);
            app.AddOrbitButton.Text = 'Add Orbit';
            app.InitialXEditFieldLabel = uilabel(app.AddOrbitsPanel);
            app.InitialXEditFieldLabel.Text = 'InitialX';
            app.InitialXEditField = uieditfield(app.AddOrbitsPanel, 'numeric');
            app.InitialXEditField.ValueChangedFcn = createCallbackFcn(app, @InitialXEditFieldValueChanged, true);
            app.InitialYEditFieldLabel = uilabel(app.AddOrbitsPanel);
            app.InitialYEditFieldLabel.Text = 'InitialY';
            app.InitialYEditField = uieditfield(app.AddOrbitsPanel, 'numeric');
            app.InitialYEditField.ValueChangedFcn = createCallbackFcn(app, @InitialYEditFieldValueChanged, true);
            app.IterationsEditFieldLabel = uilabel(app.AddOrbitsPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.AddOrbitsPanel, 'numeric');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AugmentedPhasePlane

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end